classdef appversion1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Button_4            matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        MaxEditField        matlab.ui.control.EditField
        EditField_2Label_2  matlab.ui.control.Label
        MinEditField        matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        WidthEditField      matlab.ui.control.EditField
        EditField_2Label    matlab.ui.control.Label
        HeightEditField     matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        YEditField          matlab.ui.control.EditField
        YEditFieldLabel     matlab.ui.control.Label
        XEditField          matlab.ui.control.EditField
        XEditFieldLabel     matlab.ui.control.Label
        UIAxes113           matlab.ui.control.UIAxes
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        UIAxes112           matlab.ui.control.UIAxes
        Tab2                matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Img; % 当前处理的图像
        mode_auto="auto";%自动执行模式.用于快速测试
        mode_normal="default";%正常使用模式
        ScreenFrameFlag=0;% 表示截图框打开的状态，0表示没打开,1表示打开
        scrFrame;% 表示截图框的对象，用于显示或隐藏截图框
    end
    
    methods (Access = public)
        
        function img = load_img(~,type)
            % 用于读取图片(.bmp格式)的函数，输出读取的图片
            
            if type=="auto"% 自动执行的用默认图片路径进行读取
                img= imread("D:\桌面\guangxueshiyan\Sample\10.bmp");
            elseif  type =="default"
                % 正常模式使用此函数
            
                % 显示文件选择对话框
                [filename, pathname] = uigetfile('*.bmp', 'Select BMP File');
            
                % 验证用户是否选择了文件
                if isequal(filename, 0) || isempty(filename)
                    disp('No file selected.')
                    return
                end
            
                % 构建完整文件路径
                fullFilePath = fullfile(pathname, filename);
            
                % 读取图像数据
                img = imread(fullFilePath);
            else 
               myicon= imread("D:\桌面\guangxueshiyan\myicon.jpg");
               msgbox("请输入正确路径","路径错误",myicon);
            end


        end
        function HintUIAxes(~,uiaxes)
            % 用于一键隐藏坐标轴的标题、刻度，输入需要隐藏的UIAxes
            uiaxes.Visible="off";%隐藏坐标轴刻度
            uiaxes.XLabel=[];%隐藏x轴名称
            uiaxes.YLabel=[];%隐藏y轴名称
            uiaxes.Title=[];%隐藏坐标图的标题
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_4
        function TestFcn(app, event)
            % 自动化测试，一键模拟
            ButtonPushed(app, event);
            Button_2Pushed(app, event);
            Button_3Pushed(app, event);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 点击按钮，对图像进行读取,并转为灰度图
    app.Img = rgb2gray(load_img(app,app.mode_auto));
    imshow(app.Img,'Parent',app.UIAxes112);%在坐标系中显示图像
    % 重置截图框
    app.Button_2.Text="显示截图框";
    app.ScreenFrameFlag=0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
%             ScreenFramFlag 表示有无打开截图框  默认无

        if app.ScreenFrameFlag==0
            % 全局变量scrFrame对截图框的显示和隐藏
            app.scrFrame=drawrectangle("Parent",app.UIAxes112,"Position",[200,150,250,200] ...
                ,"LineWidth",1,"color","red");
            app.Button_2.Text="隐藏截图框";
            app.ScreenFrameFlag=1;
           
        elseif app.ScreenFrameFlag==1
            delete(app.scrFrame);%通过删除截图框对象来实现隐藏截图框
%             app.scrFrame.Visible="off";或者通过改变可视化来隐藏
            app.Button_2.Text="显示截图框";
            app.ScreenFrameFlag=0;


        end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            pos= app.scrFrame.Position;
            app.XEditField.Value=num2str(pos(1));% xmin
            app.YEditField.Value=num2str(pos(2));% ymin
            app.WidthEditField.Value=num2str(pos(3)); %width
            app.HeightEditField.Value=num2str(pos(4)); % height
            % 预览截取的图像
            p=imcrop(app.Img,pos);
             imshow(p,'Parent',app.UIAxes113);
            app.MaxEditField.Value=num2str(max(max(p)));
            app.MinEditField.Value=num2str(min(min(p)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes112 = uiaxes(app.Tab);
            app.UIAxes112.XTick = [];
            app.UIAxes112.XTickLabel = '';
            app.UIAxes112.YTick = [];
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入图像';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '显示截图框';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '数据保存';
            app.UIAxes113 = uiaxes(app.Panel);
            title(app.UIAxes113, '预览截图区域')
            app.UIAxes113.XTick = [];
            app.UIAxes113.YTick = [];
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'text');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'text');
            app.Label = uilabel(app.Panel);
            app.Label.Text = '框高';
            app.HeightEditField = uieditfield(app.Panel, 'text');
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '框宽';
            app.WidthEditField = uieditfield(app.Panel, 'text');
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '获取截图数据';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '最小值';
            app.MinEditField = uieditfield(app.Panel, 'text');
            app.EditField_2Label_2 = uilabel(app.Panel);
            app.EditField_2Label_2.Text = '最大值';
            app.MaxEditField = uieditfield(app.Panel, 'text');
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @TestFcn, true);
            app.Button_4.Text = '一键测试';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appversion1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end