classdef unit_sample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button     matlab.ui.control.Button
        Label      matlab.ui.control.Label
        UIAxes113  matlab.ui.control.UIAxes
        UIAxes112  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            img=imread("D:\桌面\guangxueshiyan\myicon.jpg");
            imshow(img,"Parent",app.UIAxes113);
             app.UIAxes113.Visible="off";%隐藏坐标轴刻度
            app.UIAxes113.XLabel=[];%隐藏x轴名称
            app.UIAxes113.YLabel=[];%隐藏y轴名称
            app.UIAxes113.Title=[];%隐藏坐标图的标题
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes112 = uiaxes(app.UIFigure);
            app.UIAxes112.XTick = [];
            app.UIAxes112.XTickLabel = '';
            app.UIAxes112.YTick = [];
            app.UIAxes113 = uiaxes(app.UIFigure);
            title(app.UIAxes113, 'Title')
            xlabel(app.UIAxes113, 'X')
            ylabel(app.UIAxes113, 'Y')
            zlabel(app.UIAxes113, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '空坐标系';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unit_sample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end